classdef CalculadoraRL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        YEditField               matlab.ui.control.EditField
        YEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.EditField
        XEditFieldLabel          matlab.ui.control.Label
        calcularRegresionButton  matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
   
        end

        % Button pushed function: calcularRegresionButton
        function calcularRegresionButtonPushed(app, event)
            % Obtener datos de la tabla
            datos = app.UITable.Data;

            % Extraer las variables X e Y
            X = datos(:, 1);
            Y = datos(:, 2);

            % Realizar la regresión lineal
            [pendiente, interseccion] = regress(Y, [ones(size(X)) X]);

            % Mostrar resultados
            mensaje = sprintf('La ecuación de regresión es: y = %.2fx + %.2f', pendiente, interseccion);
            uialert(app.UIFigure, mensaje, 'Resultado', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.calcularRegresionButton = uibutton(app.UIFigure, 'push');
            app.calcularRegresionButton.ButtonPushedFcn = createCallbackFcn(app, @calcularRegresionButtonPushed, true);
            app.calcularRegresionButton.Text = 'calcularRegresion';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculadoraRL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end